classdef histogram_specification_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        TabHist            matlab.ui.container.TabGroup
        RTab               matlab.ui.container.Tab
        RHist              matlab.ui.control.UIAxes
        GTab               matlab.ui.container.Tab
        GHist              matlab.ui.control.UIAxes
        BTab               matlab.ui.container.Tab
        BHist              matlab.ui.control.UIAxes
        SelectImageButton  matlab.ui.control.Button
        ApplyButton        matlab.ui.control.Button
        TitleLabel         matlab.ui.control.Label
        RefImage           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Image EditableImage
        RefIm
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tabs = {app.TabHist, app.RefImage, app.ApplyButton};
            for i = 1:length(tabs)
                tabs{i}.Parent = [];
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if ~isempty(app.Image)
                app.Image.applyFilter('histogram_specification', {app.RefIm});
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [fileName, pathName] = uigetfile(...
                {[ ...
                '*.jpg; *.JPG; *.jpeg; *.JPEG;' ...
                '*.img; *.IMG; *.tif; *.TIF; *.tiff; *.TIFF; *.bmp; *.BMP;' ...
                '*.png; *.PNG; *.gif; *.GIF; *.webp'
                ], 'Supported Files (*.jpg,*.img,*.tiff,*.bmp)' ...
                },...
                'MultiSelect', 'off' ...
            );
            if ~isequal(fileName, 0)
                app.RefIm = imread(fullfile(pathName,fileName));
                app.TabHist.Parent = app.GridLayout;
                app.RefImage.Parent = app.GridLayout;
                app.ApplyButton.Parent = app.GridLayout;
                imshow(app.RefIm, 'Parent', app.RefImage);
                im_histg = Histogram.calc(app.RefIm);
                hist_tab = {app.RTab, app.GTab, app.BTab};
                hist_ax = {app.RHist, app.GHist, app.BHist};
                ch_name = {'R','G','B'};
                sz = size(im_histg);
                if sz(1) == 1
                    ch_name = {'Gray',''};
                end
                for i = 1:sz(1)
                    hist_tab{i}.Title = ch_name{i};
                    hist_tab{i}.Parent = [];
                    hist_tab{i}.Parent = app.TabHist;
                    hist_ax{i}.Title.String = sprintf("Reference Histogram (%s)", ch_name{i});
                    bar(hist_ax{i}, im_histg(i,:));
                end
                for i = sz(1)+1:3
                    hist_tab{i}.Parent = [];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RefImage = uiaxes(app.GridLayout);
            title(app.RefImage, 'Reference Image')
            xlabel(app.RefImage, 'X')
            ylabel(app.RefImage, 'Y')
            zlabel(app.RefImage, 'Z')
            app.TitleLabel = uilabel(app.GridLayout);
            app.TitleLabel.Text = 'Apply Histogram Specification';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SelectImageButton = uibutton(app.GridLayout, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.TabHist = uitabgroup(app.GridLayout);
            app.RTab = uitab(app.TabHist);
            app.RTab.Title = 'R';
            app.RHist = uiaxes(app.RTab);
            title(app.RHist, 'Title')
            zlabel(app.RHist, 'Z')
            app.GTab = uitab(app.TabHist);
            app.GTab.Title = 'G';
            app.GHist = uiaxes(app.GTab);
            title(app.GHist, 'Title')
            zlabel(app.GHist, 'Z')
            app.BTab = uitab(app.TabHist);
            app.BTab.Title = 'B';
            app.BHist = uiaxes(app.BTab);
            title(app.BHist, 'Title')
            zlabel(app.BHist, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogram_specification_dialog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end