classdef pengcit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenMenu                matlab.ui.container.Menu
        EditMenu                matlab.ui.container.Menu
        BasicMenu               matlab.ui.container.Menu
        BrightnessMenu          matlab.ui.container.Menu
        NegativeMenu            matlab.ui.container.Menu
        TransformationMenu      matlab.ui.container.Menu
        LogMenu                 matlab.ui.container.Menu
        PowerMenu               matlab.ui.container.Menu
        ContrastStretchingMenu  matlab.ui.container.Menu
        HistogramMenu           matlab.ui.container.Menu
        EqualizationMenu        matlab.ui.container.Menu
        SpecificationMenu       matlab.ui.container.Menu
        Toolbar                 matlab.ui.container.Toolbar
        Undo                    matlab.ui.container.toolbar.PushTool
        Redo                    matlab.ui.container.toolbar.PushTool
        Reset                   matlab.ui.container.toolbar.PushTool
        RightPanel              matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        AfterTabHist            matlab.ui.container.TabGroup
        AfterRTab               matlab.ui.container.Tab
        RAfterHist              matlab.ui.control.UIAxes
        AfterGTab               matlab.ui.container.Tab
        GAfterHist              matlab.ui.control.UIAxes
        AfterBTab               matlab.ui.container.Tab
        BAfterHist              matlab.ui.control.UIAxes
        BeforeTabHist           matlab.ui.container.TabGroup
        BeforeRTab              matlab.ui.container.Tab
        RBeforeHist             matlab.ui.control.UIAxes
        BeforeGTab              matlab.ui.container.Tab
        GBeforeHist             matlab.ui.control.UIAxes
        BeforeBTab              matlab.ui.container.Tab
        BBeforeHist             matlab.ui.control.UIAxes
        LeftPanel               matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        ModifiedLabel           matlab.ui.control.Label
        OriginalLabel           matlab.ui.control.Label
        ModifiedImage           matlab.ui.control.UIAxes
        OriginalImage           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Image EditableImage % Description
    end
    
    methods (Access = private)
        function HandleImageChange(app, ~, ~)
            app.EditMenu.Enable = "on";
            after_img = app.Image.getAfterImage;
            before_img = app.Image.getBeforeImage;
            imshow(after_img, 'Parent', app.ModifiedImage);
            imshow(before_img, 'Parent', app.OriginalImage);
            axis(app.OriginalImage,'off');
            axis(app.ModifiedImage, 'off');
            af_hist = Histogram.calc(after_img);
            bef_hist = Histogram.calc(before_img);
            af_hist_tab = {app.AfterRTab, app.AfterGTab, app.AfterBTab};
            af_hist_ax = {app.RAfterHist, app.GAfterHist, app.BAfterHist};
            bef_hist_tab = {app.BeforeRTab, app.BeforeGTab, app.BeforeBTab};
            bef_hist_ax = {app.RBeforeHist, app.GBeforeHist, app.BBeforeHist};
            ch_name = {'R','G','B'};
            sz = size(af_hist);
            if sz(1) == 1
                ch_name = {'Gray',''};
            end
            for i = 1:sz(1)
                af_hist_tab{i}.Title = ch_name{i};
                bef_hist_tab{i}.Title = ch_name{i};
                af_hist_tab{i}.Parent = [];
                bef_hist_tab{i}.Parent = [];
                af_hist_tab{i}.Parent = app.AfterTabHist;
                bef_hist_tab{i}.Parent = app.BeforeTabHist;
                af_hist_ax{i}.Title.String = sprintf("Histogram Sesudah (%s)", ch_name{i});
                bef_hist_ax{i}.Title.String = sprintf("Histogram Sebelum (%s)", ch_name{i});
                bar(af_hist_ax{i}, af_hist(i,:));
                bar(bef_hist_ax{i}, bef_hist(i,:));
            end
            for i = sz(1)+1:3
                af_hist_tab{i}.Parent = [];
                bef_hist_tab{i}.Parent = [];
            end
            c = app.Image.CurrentHistory;
            if c > 1
                app.Undo.Enable = "on";
                app.Reset.Enable = "on";
            else
                app.Undo.Enable = "off";
                app.Reset.Enable = "off";
            end
            if c < length(app.Image.History)
                app.Redo.Enable = "on";
            else
                app.Redo.Enable = "off";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.OriginalImage,'off');
            axis(app.ModifiedImage, 'off');
            tabs = {app.AfterRTab, app.AfterGTab, app.AfterBTab,...
                app.BeforeRTab, app.BeforeGTab, app.BeforeBTab};
            for i = 1:length(tabs)
                tabs{i}.Parent = [];
            end
        end

        % Menu selected function: BrightnessMenu
        function BrightnessMenuSelected(app, event)
            d = brightness_dialog;
            d.Image = app.Image;
            uiwait(d.UIFigure);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [fileName, pathName] = uigetfile(...
                {[ ...
                '*.jpg; *.JPG; *.jpeg; *.JPEG;' ...
                '*.img; *.IMG; *.tif; *.TIF; *.tiff; *.TIFF; *.bmp; *.BMP;' ...
                '*.png; *.PNG; *.gif; *.GIF; *.webp'
                ], 'Supported Files (*.jpg,*.img,*.tiff,*.bmp)' ...
                },...
                'MultiSelect', 'off' ...
            );
            if ~isequal(fileName, 0)
                app.Image = EditableImage(fullfile(pathName,fileName));
                addlistener(app.Image, 'CurrentHistory', 'PostSet', @app.HandleImageChange);
                app.HandleImageChange;
            end
        end

        % Callback function: Undo
        function UndoClicked(app, event)
            app.Image.back
        end

        % Callback function: Redo
        function RedoClicked(app, event)
            app.Image.redo
        end

        % Callback function: Reset
        function ResetClicked(app, event)
            app.Image.changeHistory(1)
        end

        % Menu selected function: NegativeMenu
        function NegativeMenuSelected(app, event)
            app.Image.applyFilter('negative')
        end

        % Menu selected function: LogMenu
        function LogMenuSelected(app, event)
            d = log_transform_dialog;
            d.Image = app.Image;
            uiwait(d.UIFigure);
        end

        % Menu selected function: PowerMenu
        function PowerMenuSelected(app, event)
            d = power_transform_dialog;
            d.Image = app.Image;
            uiwait(d.UIFigure);
        end

        % Menu selected function: ContrastStretchingMenu
        function ContrastStretchingMenuSelected(app, event)
            app.Image.applyFilter('contrast_stretching')
        end

        % Menu selected function: EqualizationMenu
        function EqualizationMenuSelected(app, event)
            app.Image.applyFilter('histogram_equalization')
        end

        % Menu selected function: SpecificationMenu
        function SpecificationMenuSelected(app, event)
            d = histogram_specification_dialog;
            d.Image = app.Image;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Enable = 'off';
            app.EditMenu.Text = 'Edit';
            app.BasicMenu = uimenu(app.EditMenu);
            app.BasicMenu.Text = 'Basic';
            app.BrightnessMenu = uimenu(app.BasicMenu);
            app.BrightnessMenu.MenuSelectedFcn = createCallbackFcn(app, @BrightnessMenuSelected, true);
            app.BrightnessMenu.Text = 'Brightness';
            app.NegativeMenu = uimenu(app.BasicMenu);
            app.NegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @NegativeMenuSelected, true);
            app.NegativeMenu.Text = 'Negative';
            app.TransformationMenu = uimenu(app.BasicMenu);
            app.TransformationMenu.Text = 'Transformation';
            app.LogMenu = uimenu(app.TransformationMenu);
            app.LogMenu.MenuSelectedFcn = createCallbackFcn(app, @LogMenuSelected, true);
            app.LogMenu.Text = 'Log';
            app.PowerMenu = uimenu(app.TransformationMenu);
            app.PowerMenu.MenuSelectedFcn = createCallbackFcn(app, @PowerMenuSelected, true);
            app.PowerMenu.Text = 'Power';
            app.ContrastStretchingMenu = uimenu(app.BasicMenu);
            app.ContrastStretchingMenu.MenuSelectedFcn = createCallbackFcn(app, @ContrastStretchingMenuSelected, true);
            app.ContrastStretchingMenu.Text = 'Contrast Stretching';
            app.HistogramMenu = uimenu(app.EditMenu);
            app.HistogramMenu.Text = 'Histogram';
            app.EqualizationMenu = uimenu(app.HistogramMenu);
            app.EqualizationMenu.MenuSelectedFcn = createCallbackFcn(app, @EqualizationMenuSelected, true);
            app.EqualizationMenu.Text = 'Equalization';
            app.SpecificationMenu = uimenu(app.HistogramMenu);
            app.SpecificationMenu.MenuSelectedFcn = createCallbackFcn(app, @SpecificationMenuSelected, true);
            app.SpecificationMenu.Text = 'Specification';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.Undo = uipushtool(app.Toolbar);
            app.Undo.ClickedCallback = createCallbackFcn(app, @UndoClicked, true);
            app.Undo.Enable = 'off';
            app.Undo.Icon = fullfile(pathToMLAPP, 'icons', 'undo.svg');
            app.Redo = uipushtool(app.Toolbar);
            app.Redo.ClickedCallback = createCallbackFcn(app, @RedoClicked, true);
            app.Redo.Enable = 'off';
            app.Redo.Icon = fullfile(pathToMLAPP, 'icons', 'redo.svg');
            app.Reset = uipushtool(app.Toolbar);
            app.Reset.ClickedCallback = createCallbackFcn(app, @ResetClicked, true);
            app.Reset.Enable = 'off';
            app.Reset.Icon = fullfile(pathToMLAPP, 'icons', 'reset.svg');
            app.LeftPanel = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.OriginalImage = uiaxes(app.GridLayout2);
            zlabel(app.OriginalImage, 'Z')
            app.OriginalImage.XTick = [];
            app.OriginalImage.YTick = [];
            app.ModifiedImage = uiaxes(app.GridLayout2);
            zlabel(app.ModifiedImage, 'Z')
            app.ModifiedImage.XTick = [];
            app.ModifiedImage.YTick = [];
            app.ModifiedImage.ZTick = [];
            app.OriginalLabel = uilabel(app.GridLayout2);
            app.OriginalLabel.Text = 'Original';
            app.ModifiedLabel = uilabel(app.GridLayout2);
            app.ModifiedLabel.Text = 'Modified';
            app.RightPanel = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.BeforeTabHist = uitabgroup(app.GridLayout3);
            app.BeforeRTab = uitab(app.BeforeTabHist);
            app.BeforeRTab.Title = 'R';
            app.RBeforeHist = uiaxes(app.BeforeRTab);
            title(app.RBeforeHist, 'Title')
            zlabel(app.RBeforeHist, 'Z')
            app.BeforeGTab = uitab(app.BeforeTabHist);
            app.BeforeGTab.Title = 'G';
            app.GBeforeHist = uiaxes(app.BeforeGTab);
            title(app.GBeforeHist, 'Title')
            zlabel(app.GBeforeHist, 'Z')
            app.BeforeBTab = uitab(app.BeforeTabHist);
            app.BeforeBTab.Title = 'B';
            app.BBeforeHist = uiaxes(app.BeforeBTab);
            title(app.BBeforeHist, 'Title')
            zlabel(app.BBeforeHist, 'Z')
            app.AfterTabHist = uitabgroup(app.GridLayout3);
            app.AfterRTab = uitab(app.AfterTabHist);
            app.AfterRTab.Title = 'R';
            app.RAfterHist = uiaxes(app.AfterRTab);
            title(app.RAfterHist, 'Title')
            zlabel(app.RAfterHist, 'Z')
            app.AfterGTab = uitab(app.AfterTabHist);
            app.AfterGTab.Title = 'G';
            app.GAfterHist = uiaxes(app.AfterGTab);
            title(app.GAfterHist, 'Title')
            zlabel(app.GAfterHist, 'Z')
            app.AfterBTab = uitab(app.AfterTabHist);
            app.AfterBTab.Title = 'B';
            app.BAfterHist = uiaxes(app.AfterBTab);
            title(app.BAfterHist, 'Title')
            zlabel(app.BAfterHist, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pengcit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end